classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ParticleSizesTab          matlab.ui.container.Tab
        R3EditField               matlab.ui.control.NumericEditField
        R3EditFieldLabel          matlab.ui.control.Label
        R2EditField               matlab.ui.control.NumericEditField
        R2EditFieldLabel          matlab.ui.control.Label
        R1EditField               matlab.ui.control.NumericEditField
        R1EditFieldLabel          matlab.ui.control.Label
        NEditField                matlab.ui.control.NumericEditField
        NEditFieldLabel           matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
        RunParticleSizeSimButton  matlab.ui.control.Button
        ConcentrationsTab         matlab.ui.container.Tab
        RunConcentationSimButton  matlab.ui.control.Button
        Mult3EditField            matlab.ui.control.NumericEditField
        Mult3EditFieldLabel       matlab.ui.control.Label
        Mult2EditField            matlab.ui.control.NumericEditField
        Mult2EditFieldLabel       matlab.ui.control.Label
        Mult1EditField            matlab.ui.control.NumericEditField
        Mult1EditFieldLabel       matlab.ui.control.Label
        REditField                matlab.ui.control.NumericEditField
        REditFieldLabel           matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function Esca_sum = sumScatter(app,N,L,k0,S,Einc,freq)
            Esca_sum = zeros(size(freq));

            for n = 1:N
                particleR = rand()*L;
                Esca = ForwardScatteringEnergy(k0, particleR, S, Einc);  
                Esca_sum = Esca_sum + Esca;
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunParticleSizeSimButton
        function RunParticleSizeSimButtonPushed(app, event)
            L = 0.02;                               % reciever dist (m)
            [freq , Einc]= readfromcsv('0M.csv');
            k0 = 2*pi*freq/3e8;                     % wave number
            m = 1.8;   

            N = app.NEditField.Value;
            R1 = app.R1EditField.Value;
            R2 = app.R2EditField.Value;
            R3 = app.R3EditField.Value;

            x1 = k0 * R1; 
            x2 = k0 * R2; 
            x3 = k0 * R3;

            S_1 = S1(0,m,x1); 
            S_2 = S1(0,m,x2);
            S_3 = S1(0,m,x3); 

            Esca_sum1 = app.sumScatter(N,L,k0,S_1,Einc,freq);
            Esca_sum2 = app.sumScatter(N,L,k0,S_2,Einc,freq);
            Esca_sum3 = app.sumScatter(N,L,k0,S_3,Einc,freq);

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            plot(app.UIAxes, freq/1e6, abs(Esca_sum1), 'DisplayName', 'R1');
            plot(app.UIAxes, freq/1e6, abs(Esca_sum2), 'DisplayName', 'R2');
            plot(app.UIAxes, freq/1e6, abs(Esca_sum3), 'DisplayName', 'R3');

            xlabel(app.UIAxes, 'frequency (in Mhz)');
            ylabel(app.UIAxes, 'Energy');
            title(app.UIAxes, ['Scattered Energy || N =  ',num2str(N)]);
            legend(app.UIAxes); 
            grid(app.UIAxes, "on");
        end

        % Button pushed function: RunConcentationSimButton
        function RunConcentationSimButtonPushed(app, event)
             L = 0.02;                               % reciever dist (m)
            [freq , Einc]= readfromcsv('0M.csv');
            k0 = 2*pi*freq/3e8;                     % wave number
            m = 1.8;   

            R = app.REditField.Value;
            mult1 = app.Mult1EditField.Value;
            mult2 = app.Mult2EditField.Value;
            mult3 = app.Mult3EditField.Value;

            x = k0 * R; 
            S = S1(0,m,x); 
           
            N =((0.01*0.01*0.02)/(pi*R^2)); 
            N1 = round(N*(mult1)); 
            N2 = round(N*(mult2));  
            N3 = round(N*(mult3));

            Esca_sum1 = app.sumScatter(N1,L,k0,S,Einc,freq);
            Esca_sum2 = app.sumScatter(N2,L,k0,S,Einc,freq);
            Esca_sum3 = app.sumScatter(N3,L,k0,S,Einc,freq);

            cla(app.UIAxes2);
            hold(app.UIAxes2, 'on');

            plot(app.UIAxes2, freq/1e6, abs(Esca_sum1), 'DisplayName', 'N1');
            plot(app.UIAxes2, freq/1e6, abs(Esca_sum2), 'DisplayName', 'N2');
            plot(app.UIAxes2, freq/1e6, abs(Esca_sum3), 'DisplayName', 'N3');

            xlabel(app.UIAxes2, 'frequency (in Mhz)');
            ylabel(app.UIAxes2, 'Energy');
            title(app.UIAxes2, ['Scattered Energy || R =  ',num2str(R)]);
            legend(app.UIAxes2); 
            grid(app.UIAxes2, "on");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParticleSizesTab = uitab(app.TabGroup);
            app.ParticleSizesTab.Title = 'Particle Sizes';
            app.RunParticleSizeSimButton = uibutton(app.ParticleSizesTab, 'push');
            app.RunParticleSizeSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunParticleSizeSimButtonPushed, true);
            app.RunParticleSizeSimButton.Text = 'Run Particle Size Sim';
            app.UIAxes = uiaxes(app.ParticleSizesTab);
            title(app.UIAxes, 'Scattering Energy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NEditFieldLabel = uilabel(app.ParticleSizesTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.ParticleSizesTab, 'numeric');
            app.NEditField.Value = 10000;
            app.R1EditFieldLabel = uilabel(app.ParticleSizesTab);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1EditField = uieditfield(app.ParticleSizesTab, 'numeric');
            app.R1EditField.Value = 4e-06;
            app.R2EditFieldLabel = uilabel(app.ParticleSizesTab);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2EditField = uieditfield(app.ParticleSizesTab, 'numeric');
            app.R2EditField.Value = 8e-06;
            app.R3EditFieldLabel = uilabel(app.ParticleSizesTab);
            app.R3EditFieldLabel.Text = 'R3';
            app.R3EditField = uieditfield(app.ParticleSizesTab, 'numeric');
            app.R3EditField.Value = 1e-05;
            app.ConcentrationsTab = uitab(app.TabGroup);
            app.ConcentrationsTab.Title = 'Concentrations';
            app.UIAxes2 = uiaxes(app.ConcentrationsTab);
            title(app.UIAxes2, 'Scattering Energy')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.REditFieldLabel = uilabel(app.ConcentrationsTab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.REditField.Value = 5e-06;
            app.Mult1EditFieldLabel = uilabel(app.ConcentrationsTab);
            app.Mult1EditFieldLabel.Text = 'Mult1';
            app.Mult1EditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.Mult1EditField.Value = 0.001;
            app.Mult2EditFieldLabel = uilabel(app.ConcentrationsTab);
            app.Mult2EditFieldLabel.Text = 'Mult2';
            app.Mult2EditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.Mult2EditField.Value = 0.0001;
            app.Mult3EditFieldLabel = uilabel(app.ConcentrationsTab);
            app.Mult3EditFieldLabel.Text = 'Mult3';
            app.Mult3EditField = uieditfield(app.ConcentrationsTab, 'numeric');
            app.Mult3EditField.Value = 1e-05;
            app.RunConcentationSimButton = uibutton(app.ConcentrationsTab, 'push');
            app.RunConcentationSimButton.ButtonPushedFcn = createCallbackFcn(app, @RunConcentationSimButtonPushed, true);
            app.RunConcentationSimButton.Text = 'Run Concentation Sim';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end